classdef WaveEquationVis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PDEsWaveEquationVisualizerUIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        SimulationControlPanel       matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        RunButton                    matlab.ui.control.StateButton
        ClearButton                  matlab.ui.control.Button
        SplineToolsPanel             matlab.ui.container.Panel
        GridLayout2_2                matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        InterpolationModeLabel       matlab.ui.control.Label
        InterpModeDropDown           matlab.ui.control.DropDown
        InitialConditionButtonGroup  matlab.ui.container.ButtonGroup
        VelocityButton               matlab.ui.control.RadioButton
        DisplacementButton           matlab.ui.control.RadioButton
        ResetButton_2                matlab.ui.control.Button
        EditButton                   matlab.ui.control.StateButton
        RightPanel                   matlab.ui.container.Panel
        SimulationPanel              matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        %% initial velocity and displacement spline properties
        active_spline     % active spline object
        vel_spline        % spline object for velocity of the wave
        dis_spline        % spline object for displacement of the wave
        
        %% simulation properties
        Nspace = 100;  % set the number of spatial points
        rate_control = rateControl(30);
    end

    methods (Access=private)

        % Callback function for spline update
        function Mouse_Callback(app,~,~,action)
            persistent ctrl_xs ctrl_ys ctrl_idx
            pos = get(app.UIAxes ,'CurrentPoint');

            switch action
                case 'down'
                    ctrl_xs = get(app.active_spline.ctrl_plot,'xdata');
                    ctrl_ys = get(app.active_spline.ctrl_plot,'ydata');
                    [~,ctrl_idx] = min(sum((ctrl_xs-pos(1)).^2+(ctrl_ys-pos(3)).^2,1));
                    set(app.PDEsWaveEquationVisualizerUIFigure,...
                        'WindowButtonMotionFcn',  {@app.Mouse_Callback,'move'},...
                        'WindowButtonUpFcn',      {@app.Mouse_Callback,'up'});
                case 'move'
                    % vertical move control point
                    ctrl_ys(ctrl_idx) = pos(3);
                    set(app.active_spline.ctrl_plot,'ydata',ctrl_ys)

                    % update spline
                    app.active_spline.update(ctrl_xs, ctrl_ys);

                case 'up'
                    set(app.PDEsWaveEquationVisualizerUIFigure,...
                        'WindowButtonMotionFcn',  '',...
                        'WindowButtonUpFcn',      '');
            end
        end
        
        function reset_spline_builder(app)
            % if sim is running, do nothing
            if app.RunButton.Value == true
                return
            end

            % clear wave axes
            cla(app.UIAxes)

            % plot both splines with control points
            hold(app.UIAxes,'on');
            app.dis_spline.plot(true, "Color", "r");
            app.vel_spline.plot(true, "Color", "b");
            legend(app.UIAxes, "displacement", "", "velocity", "")
            ylim(app.UIAxes, [-2 2])

            % reset spline to a flat line at the x axis
            app.active_spline.reset();
        end
        
        function run_simulation(app)
            %% Definitions
            c = 1;          % set the wave speed
            L = 10;         % set the length of the domain
            T = 20;         % set the total time (2*L/c)
            dt = 0.1;       % set the time step
            Nmodes = 100;   % set the number of modes

            %% Set the mode amplitudes directly
            A = zeros(Nmodes,1); % initialize cosine mode amplitudes = 0
            A(2) = 1; % set A_1 = 1
            B = zeros(Nmodes,1); % initialize sine mode amplitudes = 0
            B(2) = 1/4; % set B_1 = 1

            eff = @(X) ppval(app.dis_spline.get_pp(), X);
            gee = @(X) ppval(app.vel_spline.get_pp(), X);

            fplot(app.UIAxes, @(X) [eff(X),gee(X)],[0,L])
            for n = 1:Nmodes
                A(n) = 2*integral(@(X) eff(X).*sin(n*pi*X/L),0,L)/L; % define the An coefficient
                B(n) = 2*integral(@(X) gee(X).*sin(n*pi*X/L),0,L)/(n*pi*c); % define the Bn coefficient
            end

            %% Compute the solution and visualize
            % xx = linspace(-L,2*L,Nspace); % define a set of points on a larger domain
            xx = linspace(0,L,app.Nspace); % define a set of points on a larger domain
            j = 1; % set a counter at the start of the time loop
            for t = 0:dt:T % loop through time
                % if run button is disbled, stop
                if app.RunButton.Value == false
                    break
                end
                uL = zeros(1,app.Nspace); % set the left traveling solution to zero
                uR = zeros(1,app.Nspace); % set the right traveling solution to zero
                u = zeros(1,app.Nspace); % set the solution to zero
                for n = 1:Nmodes % loop through the modes
                    uL = uL + 0.5*(A(n)*sin(n*pi*(xx+c*t)/L) - B(n)*cos(n*pi*(xx+c*t)/L)); % update the left traveling solution
                    uR = uR + 0.5*(A(n)*sin(n*pi*(xx-c*t)/L) + B(n)*cos(n*pi*(xx-c*t)/L)); % update the right traveling solution
                    u = uL + uR; % update the total solution
                end
                plot(app.UIAxes,xx,uL,"Color",[0 0 1 .5]), hold(app.UIAxes, "on"); % plot the left traveling solution
                plot(app.UIAxes,xx,uR,"Color",[1 0 0 .5]) % plot the right traveling solution
                plot(app.UIAxes,xx,u, 'k', LineWidth=2) % plot the total solution
                %     axis([-L 2*L -2 2]) % set the axes
                axis(app.UIAxes, [0 L -2 2]) % set the axes
                xlabel(app.UIAxes, 'x')
                ylabel(app.UIAxes, 'u')
                title(app.UIAxes, ['time = ', num2str(t)])
                drawnow % force the graphics
                hold(app.UIAxes, "off");
                usave(j,:) = u; % save the solution
                tsave(j) = t; % save the time
                j = j+1; % advance the time counter
                
                if j > 2
% TODO: for speed don't recalculate this each iteration
                    [XX,T]=meshgrid(xx,tsave); % make the meshgrid for plotting
                    pcolor(app.UIAxes2, XX,T,usave), shading(app.UIAxes2, "interp"); % plot the solution as a pseudo-color contour plot
                end
                
                waitfor(app.rate_control);
            end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Init wave view spline builder
            % set pan mode off because I don't like it
            disableDefaultInteractivity(app.UIAxes);

            % define control points
            ctrl_xs = 0:10;
            ctrl_ys = zeros(1, 11);

            % define spline between control points
            app.dis_spline = Spline(ctrl_xs, ctrl_ys, app.Nspace, app.UIAxes, "displacement");
            app.vel_spline = Spline(ctrl_xs, ctrl_ys, app.Nspace, app.UIAxes, "velocity");

            % set active spline
            app.active_spline = app.dis_spline;

            % set axes properties
            hold(app.UIAxes,'on');
            xlim(app.UIAxes, [-.5 10.5]); ylim(app.UIAxes, [-5 5]);

            % init plot for spline builder
            app.reset_spline_builder();

            % set up sim history plot
            ylim(app.UIAxes2, [0 20])
            colorbar(app.UIAxes2)
            clim(app.UIAxes2, [-2 2])
        end

        % Callback function: EditButton, InterpModeDropDown
        function EditButtonValueChanged(app, event)
            active = app.EditButton.Value;
            if active
                % enable click and drag spline editing
                set(app.UIAxes,...
                        'buttondownfcn', {@app.Mouse_Callback,"down"});
            else
                % disable click and drag spline editing
                set(app.UIAxes,'buttondownfcn','');
            end
        end

        % Value changed function: InterpModeDropDown
        function InterpModeDropDownValueChanged(app, event)
            value = app.InterpModeDropDown.Value;
            app.active_spline.method = value;
            app.active_spline.update(app.active_spline.ctrl_xs, app.active_spline.ctrl_ys);
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            active = app.RunButton.Value;
            if active
                % turn off legend for speed
                legend(app.UIAxes, "off")

                app.RunButton.Text = "End";
                app.EditButton.Value = 0;

                app.run_simulation();
            else
                app.RunButton.Text = "Run";
                app.EditButton.Value = 1;

            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.RunButton.Value = 0;
            app.RunButton.Text = "Run";
            app.EditButton.Value = 1;

            % clear sim history axes
            cla(app.UIAxes2)
            xlim(app.UIAxes2, [0 10])
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            % for the wave view reset button

            % reset spline builder control points
            app.reset_spline_builder();
        end

        % Selection changed function: InitialConditionButtonGroup
        function InitialConditionButtonGroupSelectionChanged(app, event)
            selectedButton = app.InitialConditionButtonGroup.SelectedObject;

            % switch app's active spline
            switch selectedButton.Text
                case "Displacement"
                    app.active_spline = app.dis_spline;
                case "Velocity"
                    app.active_spline = app.vel_spline;
            end

            % switch dropdown interp mode value
            app.InterpModeDropDown.Value = app.active_spline.method;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PDEsWaveEquationVisualizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {486, 486};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PDEsWaveEquationVisualizerUIFigure = uifigure('Visible', 'off');
            app.PDEsWaveEquationVisualizerUIFigure.Name = 'PDEs Wave Equation Visualizer';
            app.PDEsWaveEquationVisualizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PDEsWaveEquationVisualizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SplineToolsPanel = uipanel(app.GridLayout2);
            app.SplineToolsPanel.Title = 'Spline Tools';
            app.GridLayout2_2 = uigridlayout(app.SplineToolsPanel);
            app.EditButton = uibutton(app.GridLayout2_2, 'state');
            app.EditButton.ValueChangedFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.EditButton.Text = 'Edit';
            app.ResetButton_2 = uibutton(app.GridLayout2_2, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Text = 'Reset';
            app.InitialConditionButtonGroup = uibuttongroup(app.GridLayout2_2);
            app.InitialConditionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InitialConditionButtonGroupSelectionChanged, true);
            app.InitialConditionButtonGroup.Title = 'Initial Condition';
            app.DisplacementButton = uiradiobutton(app.InitialConditionButtonGroup);
            app.DisplacementButton.Text = 'Displacement';
            app.DisplacementButton.Value = true;
            app.VelocityButton = uiradiobutton(app.InitialConditionButtonGroup);
            app.VelocityButton.Text = 'Velocity';
            app.GridLayout5 = uigridlayout(app.GridLayout2_2);
            app.InterpModeDropDown = uidropdown(app.GridLayout5);
            app.InterpModeDropDown.Items = {'pchip', 'cubic', 'linear'};
            app.InterpModeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @EditButtonValueChanged, true);
            app.InterpModeDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpModeDropDownValueChanged, true);
            app.InterpModeDropDown.Value = 'pchip';
            app.InterpolationModeLabel = uilabel(app.GridLayout5);
            app.InterpolationModeLabel.Text = 'Interpolation Mode';
            app.SimulationControlPanel = uipanel(app.GridLayout2);
            app.SimulationControlPanel.Title = 'Simulation Control';
            app.GridLayout3 = uigridlayout(app.SimulationControlPanel);
            app.ClearButton = uibutton(app.GridLayout3, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RunButton = uibutton(app.GridLayout3, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = 'Run';
            app.RightPanel = uipanel(app.GridLayout);
            app.SimulationPanel = uipanel(app.RightPanel);
            app.SimulationPanel.Title = 'Simulation';
            app.UIAxes = uiaxes(app.SimulationPanel);
            title(app.UIAxes, 'Wave View')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'u')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.SimulationPanel);
            title(app.UIAxes2, 'Simulation History')
            xlabel(app.UIAxes2, 'x')
            ylabel(app.UIAxes2, 't')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.PDEsWaveEquationVisualizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WaveEquationVis

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PDEsWaveEquationVisualizerUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.PDEsWaveEquationVisualizerUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PDEsWaveEquationVisualizerUIFigure)
        end
    end
end